classdef Clicky_GUI_For_Chrissi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LaserCommanderPanel             matlab.ui.container.Panel
        LaserSerialPortDropDownLabel    matlab.ui.control.Label
        LaserSerialPortDropDown         matlab.ui.control.DropDown
        RefreshButton                   matlab.ui.control.Button
        ConnectToLaserButton            matlab.ui.control.Button
        UpdateLaserStatusButton         matlab.ui.control.Button
        LaserTunedLampLabel             matlab.ui.control.Label
        LaserTunedLamp                  matlab.ui.control.Lamp
        LaserStatusLabel                matlab.ui.control.Label
        ShutterStatusLampLabel          matlab.ui.control.Label
        ShutterStatusLamp               matlab.ui.control.Lamp
        ShutterStatusLabel              matlab.ui.control.Label
        ShutterCommandButton            matlab.ui.control.Button
        LaserDAQDeviceNameEditFieldLabel  matlab.ui.control.Label
        LaserDAQDeviceNameEditField     matlab.ui.control.EditField
        BiasAOEditFieldLabel            matlab.ui.control.Label
        BiasAOEditField                 matlab.ui.control.EditField
        CMDAOEditFieldLabel             matlab.ui.control.Label
        CMDAOEditField                  matlab.ui.control.EditField
        SITriggerAOEditFieldLabel       matlab.ui.control.Label
        SITriggerAOEditField            matlab.ui.control.EditField
        WavelengthEditField_2Label      matlab.ui.control.Label
        ManualWavelengthEditField       matlab.ui.control.EditField
        ManuallyTuneButton              matlab.ui.control.Button
        SpectrumCommanderPanel          matlab.ui.container.Panel
        WriteCommandFileforSpectrumButton  matlab.ui.control.Button
        NextWavelengthButton            matlab.ui.control.Button
        NumberOfWavelengthsToTestLabel  matlab.ui.control.Label
        WavelengthIDEditFieldLabel      matlab.ui.control.Label
        WavelengthIDEditField           matlab.ui.control.NumericEditField
        WavelengthEditFieldLabel        matlab.ui.control.Label
        WavelengthEditField             matlab.ui.control.NumericEditField
        BiasEditFieldLabel              matlab.ui.control.Label
        BiasEditField                   matlab.ui.control.NumericEditField
        SETCOMMANDTOEditFieldLabel      matlab.ui.control.Label
        SETCOMMANDTOEditField           matlab.ui.control.NumericEditField
        EquiphotonInspectorPanel        matlab.ui.container.Panel
        SinFitPlot                      matlab.ui.control.UIAxes
        EquiphotonFluxPlot              matlab.ui.control.UIAxes
        PowerAtEquiphotonFlux           matlab.ui.control.UIAxes
        CommandValueSliderLabel         matlab.ui.control.Label
        CommandValueSlider              matlab.ui.control.Slider
        WavelengthSliderLabel           matlab.ui.control.Label
        WavelengthSlider                matlab.ui.control.Slider
        CommandVoltageLabel             matlab.ui.control.Label
        WavelengthLabel                 matlab.ui.control.Label
        DataTable                       matlab.ui.control.Table
        PhotonsPerPulseData             matlab.ui.control.Label
        PhotonsPerPulseLabel            matlab.ui.control.Label
        PowerAtThisCommandValueData     matlab.ui.control.Label
        PoweratthisCommandValueLabel    matlab.ui.control.Label
        BiasVoltageatthisWavelengthData  matlab.ui.control.Label
        BiasVoltageatthisWavelengthLabel  matlab.ui.control.Label
        FrontPanelBiasatthisWavelengthData  matlab.ui.control.Label
        FrontPanelBiasatthisWavelengthLabel  matlab.ui.control.Label
        FilenameEditFieldLabel          matlab.ui.control.Label
        FilenameEditField               matlab.ui.control.EditField
    end

    
    properties (Access = private)
        Laser % Description
        WavelengthsWithBiasValues % Description
        BiasValuesForEachWavelength % Description
        DAQ_bias_session % Description
    end
    
    methods (Access = private)
        
        function results = updatePlots(app)
            wavelength = app.WavelengthSlider.Value;
            CMD = app.CommandValueSlider.Value;
            
            [photon_flux_at_wavelength, equi_photon_cmd_value, unique_wavelengths, milliwatts_at_command_at_wavelength, bias_voltages, front_panel_bias, filename] = Compute_CMD_Values_Func(wavelength, CMD, app.SinFitPlot, app.EquiphotonFluxPlot, app.PowerAtEquiphotonFlux);
            
            app.DataTable.Data = [cellstr(num2str(unique_wavelengths, '%.0f')), cellstr(num2str(bias_voltages, '%.3f')), cellstr(num2str(round(front_panel_bias))), cellstr(num2str(100*(equi_photon_cmd_value), '%.1f')), cellstr(num2str(milliwatts_at_command_at_wavelength, '%.0f'))];
            app.PhotonsPerPulseData.Text = num2str(photon_flux_at_wavelength, 4);
            app.PowerAtThisCommandValueData.Text = [num2str(round(milliwatts_at_command_at_wavelength(unique_wavelengths == wavelength))), ' mW'];
            app.BiasVoltageatthisWavelengthData.Text = [num2str(round(bias_voltages(unique_wavelengths == wavelength),3)), ' V'];
            app.FrontPanelBiasatthisWavelengthData.Text = [num2str(round(front_panel_bias(unique_wavelengths == wavelength)))];
            
            app.WavelengthsWithBiasValues = unique_wavelengths;
            app.BiasValuesForEachWavelength = bias_voltages;
            
            
            app.FilenameEditField.Value = filename;
        end
        
        function matrixRowsShuffled = shuffleRows(app,matrix)
            matrixRowsShuffled = matrix(randperm(size(matrix, 1)), :);
        end
        
        function [shutter_status] = CheckShutterStatus(app)
            % check the shutter status
            shutter_status = str2double(app.Laser.queryShutterStatus());
            if shutter_status == 0
                % shutter is open
                app.ShutterStatusLamp.Color = [0, 1, 0];
                app.ShutterStatusLabel.Text = 'Shutter is Closed';
                app.ShutterCommandButton.Text = 'Open Shutter';
            else
                app.ShutterStatusLamp.Color = [1, 0, 0];
                app.ShutterStatusLabel.Text = 'SHUTTER IS OPEN';
                app.ShutterCommandButton.Text = 'Close Shutter';
            end
        end
        
        function [tuned_status] = CheckTunedStatus(app)
            tuned_status = str2double(app.Laser.queryTunedStatus());
            if tuned_status == 0
                % shutter is open
                app.LaserTunedLamp.Color = [0, 1, 0];
                app.LaserStatusLabel.Text = 'Laser is Tuned';
            else
                app.LaserTunedLamp.Color = [1, 0, 0];
                app.LaserStatusLabel.Text = 'LASER IS NOT TUNED';
            end
            
            % TODO: check the wavelength
        end
        
        function [shutter_status, tuned_status] = CheckLaserStatus(app)
            shutter_status = app.CheckShutterStatus();
            tuned_status = app.CheckTunedStatus();
        end
        
        function SetWavelengthAndBias(app, wavelength)
            app.LaserTunedLamp.Color = [1, 0, 0];
            app.Laser.setWavelengthBlocking(wavelength);
            app.LaserTunedLamp.Color = [0, 1, 0];
            
            %set the bias value
            bias_value = app.BiasValuesForEachWavelength(app.WavelengthsWithBiasValues == wavelength);
            app.SetBiasValue(bias_value);
        end
        
        function SetBiasValue(app, bias)
            queueOutputData(app.DAQ_bias_session,bias);
            startForeground(app.DAQ_bias_session);
        end
        
        function ConnectToNIForBiasSetting(app)
            s = daq.createSession('ni');
            addAnalogOutputChannel(s,app.LaserDAQDeviceNameEditField.Value,str2double(app.BiasAOEditField.Value),'Voltage')
            s.Rate = 10000;
            
            app.DAQ_bias_session = s;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LaserSerialPortDropDown.Items = serialportlist('available');
            
            app.LaserTunedLamp.Color = [.1, .1, .1];
            app.ShutterStatusLamp.Color = [.1, .1, .1];
            
            app.updatePlots();
            
            app.ConnectToNIForBiasSetting();
        end

        % Value changed function: CommandValueSlider
        function CommandValueSliderValueChanged(app, event)
            app.CommandValueSlider.Value = round(app.CommandValueSlider.Value, 3);
            app.CommandVoltageLabel.Text = [num2str(app.CommandValueSlider.Value), ' V'];
            app.updatePlots();
        end

        % Value changed function: WavelengthSlider
        function WavelengthSliderValueChanged(app, event)
            app.WavelengthSlider.Value = round(app.WavelengthSlider.Value/10)*10;
            app.WavelengthLabel.Text = [num2str(app.WavelengthSlider.Value), ' nm'];
            app.updatePlots();
        end

        % Button pushed function: WriteCommandFileforSpectrumButton
        function WriteCommandFileforSpectrumButtonPushed(app, event)
            formatOut = 'yyyy_mm_dd_HH_MM';
            shuffled_matrix = str2double(app.shuffleRows(app.DataTable.Data));
            shuffled_matrix = shuffled_matrix(~isnan(shuffled_matrix(:,4)),:); %take out all rows where the command is nan
            
            writematrix(shuffled_matrix, ['./data/spectrum_', datestr(now,formatOut), '.csv']);
        end

        % Button pushed function: NextWavelengthButton
        function NextWavelengthButtonPushed(app, event)
            %connect to the laser
            % SHOULD CHECK IF WE ARE ACTUALLY CONNECTED TO THE LASER
            
            %read in the shuffled matrix data
            folder = './data/';
            S = dir([folder, 'spectrum_*.csv']);
            S = S(~[S.isdir]);
            [~,idx] = sort([S.datenum], 'descend');
            S = S(idx);
            
            current_id = app.WavelengthIDEditField.Value + 1;
            
            spectrum_matrix = readmatrix([folder, S(1).name]);
            wavelengths = spectrum_matrix(:,1);
            biases = spectrum_matrix(:,2);
            commands = spectrum_matrix(:,4);
            
            
            % update the gui fields
            app.NumberOfWavelengthsToTestLabel.Text = ['of ', num2str(length(wavelengths))];
            
            if(current_id == length(wavelengths))
                app.NextWavelengthButton.Enable = 'off';
            end
            
            app.WavelengthEditField.Value = wavelengths(current_id);
            app.BiasEditField.Value = biases(current_id);
            app.SETCOMMANDTOEditField.Value = commands(current_id);
            
            app.CheckLaserStatus();
            
            % tune the laser to the new wavelength
            app.LaserTunedLamp.Color = [1, 0, 0];
            app.SetWavelengthAndBias(wavelengths(current_id));
            app.LaserTunedLamp.Color = [0, 1, 0];
            
            app.WavelengthIDEditField.Value = app.WavelengthIDEditField.Value + 1;
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.LaserSerialPortDropDown.Items = serialportlist('available');
        end

        % Button pushed function: ConnectToLaserButton
        function ConnectToLaserButtonPushed(app, event)
            app.Laser = Chameleon(app.LaserSerialPortDropDown.Value, true);
            % check if the shutter is open and if the laser is tuned
            app.CheckLaserStatus();
        end

        % Button pushed function: ShutterCommandButton
        function ShutterCommandButtonPushed(app, event)
            [shutter_status, ~] = app.CheckLaserStatus();
            
            if shutter_status == 0
                %open the shutter
                app.Laser.openShutterBlocking();
            else
                %close the shutter 
                app.Laser.closeShutterBlocking();
            end
            
            app.CheckLaserStatus();
        end

        % Button pushed function: ManuallyTuneButton
        function ManuallyTuneButtonPushed(app, event)
            manual_wavelength_value = str2num(app.ManualWavelengthEditField.Value);
            
            %check if the input wavelength is in the list of wavelengths
            %for which we know the bias value (would also catch if non
            %numeric)
            if sum(app.WavelengthsWithBiasValues == manual_wavelength_value) == 0
                app.ManualWavelengthEditField.Value = '';
                return
            end
            
            app.SetWavelengthAndBias(manual_wavelength_value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LaserCommanderPanel = uipanel(app.UIFigure);
            app.LaserCommanderPanel.Title = 'Laser Commander';
            app.LaserSerialPortDropDownLabel = uilabel(app.LaserCommanderPanel);
            app.LaserSerialPortDropDownLabel.Text = 'Laser Serial Port';
            app.LaserSerialPortDropDown = uidropdown(app.LaserCommanderPanel);
            app.RefreshButton = uibutton(app.LaserCommanderPanel, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            app.ConnectToLaserButton = uibutton(app.LaserCommanderPanel, 'push');
            app.ConnectToLaserButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectToLaserButtonPushed, true);
            app.ConnectToLaserButton.Text = 'Connect To Laser';
            app.UpdateLaserStatusButton = uibutton(app.LaserCommanderPanel, 'push');
            app.UpdateLaserStatusButton.Text = 'Update Laser Status';
            app.LaserTunedLampLabel = uilabel(app.LaserCommanderPanel);
            app.LaserTunedLampLabel.Text = 'Laser Tuned';
            app.LaserTunedLamp = uilamp(app.LaserCommanderPanel);
            app.LaserStatusLabel = uilabel(app.LaserCommanderPanel);
            app.LaserStatusLabel.Text = 'Laser Status Label';
            app.ShutterStatusLampLabel = uilabel(app.LaserCommanderPanel);
            app.ShutterStatusLampLabel.Text = 'Shutter Status';
            app.ShutterStatusLamp = uilamp(app.LaserCommanderPanel);
            app.ShutterStatusLabel = uilabel(app.LaserCommanderPanel);
            app.ShutterStatusLabel.Text = 'Shutter Status Label';
            app.ShutterCommandButton = uibutton(app.LaserCommanderPanel, 'push');
            app.ShutterCommandButton.ButtonPushedFcn = createCallbackFcn(app, @ShutterCommandButtonPushed, true);
            app.ShutterCommandButton.Text = 'Shutter Command';
            app.LaserDAQDeviceNameEditFieldLabel = uilabel(app.LaserCommanderPanel);
            app.LaserDAQDeviceNameEditFieldLabel.Text = 'Laser DAQ Device Name';
            app.LaserDAQDeviceNameEditField = uieditfield(app.LaserCommanderPanel, 'text');
            app.LaserDAQDeviceNameEditField.Value = 'Dev1';
            app.BiasAOEditFieldLabel = uilabel(app.LaserCommanderPanel);
            app.BiasAOEditFieldLabel.Text = 'Bias AO';
            app.BiasAOEditField = uieditfield(app.LaserCommanderPanel, 'text');
            app.BiasAOEditField.Value = '0';
            app.CMDAOEditFieldLabel = uilabel(app.LaserCommanderPanel);
            app.CMDAOEditFieldLabel.Text = 'CMD AO';
            app.CMDAOEditField = uieditfield(app.LaserCommanderPanel, 'text');
            app.CMDAOEditField.Value = '1';
            app.SITriggerAOEditFieldLabel = uilabel(app.LaserCommanderPanel);
            app.SITriggerAOEditFieldLabel.Text = 'SI Trigger AO';
            app.SITriggerAOEditField = uieditfield(app.LaserCommanderPanel, 'text');
            app.SITriggerAOEditField.Value = 'user1';
            app.WavelengthEditField_2Label = uilabel(app.LaserCommanderPanel);
            app.WavelengthEditField_2Label.Text = 'Wavelength';
            app.ManualWavelengthEditField = uieditfield(app.LaserCommanderPanel, 'text');
            app.ManuallyTuneButton = uibutton(app.LaserCommanderPanel, 'push');
            app.ManuallyTuneButton.ButtonPushedFcn = createCallbackFcn(app, @ManuallyTuneButtonPushed, true);
            app.ManuallyTuneButton.Text = 'Manually Tune';
            app.SpectrumCommanderPanel = uipanel(app.UIFigure);
            app.SpectrumCommanderPanel.Title = 'Spectrum Commander';
            app.WriteCommandFileforSpectrumButton = uibutton(app.SpectrumCommanderPanel, 'push');
            app.WriteCommandFileforSpectrumButton.ButtonPushedFcn = createCallbackFcn(app, @WriteCommandFileforSpectrumButtonPushed, true);
            app.WriteCommandFileforSpectrumButton.Text = 'Write Command File for Spectrum';
            app.NextWavelengthButton = uibutton(app.SpectrumCommanderPanel, 'push');
            app.NextWavelengthButton.ButtonPushedFcn = createCallbackFcn(app, @NextWavelengthButtonPushed, true);
            app.NextWavelengthButton.Text = 'Next Wavelength';
            app.NumberOfWavelengthsToTestLabel = uilabel(app.SpectrumCommanderPanel);
            app.NumberOfWavelengthsToTestLabel.Text = 'of ';
            app.WavelengthIDEditFieldLabel = uilabel(app.SpectrumCommanderPanel);
            app.WavelengthIDEditFieldLabel.Text = 'Wavelength ID';
            app.WavelengthIDEditField = uieditfield(app.SpectrumCommanderPanel, 'numeric');
            app.WavelengthEditFieldLabel = uilabel(app.SpectrumCommanderPanel);
            app.WavelengthEditFieldLabel.Text = 'Wavelength';
            app.WavelengthEditField = uieditfield(app.SpectrumCommanderPanel, 'numeric');
            app.BiasEditFieldLabel = uilabel(app.SpectrumCommanderPanel);
            app.BiasEditFieldLabel.Text = 'Bias';
            app.BiasEditField = uieditfield(app.SpectrumCommanderPanel, 'numeric');
            app.SETCOMMANDTOEditFieldLabel = uilabel(app.SpectrumCommanderPanel);
            app.SETCOMMANDTOEditFieldLabel.Text = 'SET COMMAND TO';
            app.SETCOMMANDTOEditField = uieditfield(app.SpectrumCommanderPanel, 'numeric');
            app.EquiphotonInspectorPanel = uipanel(app.UIFigure);
            app.EquiphotonInspectorPanel.Title = 'Equiphoton Inspector';
            app.SinFitPlot = uiaxes(app.EquiphotonInspectorPanel);
            title(app.SinFitPlot, 'Sine Fit Plot')
            xlabel(app.SinFitPlot, '')
            ylabel(app.SinFitPlot, 'Photon Count')
            app.EquiphotonFluxPlot = uiaxes(app.EquiphotonInspectorPanel);
            title(app.EquiphotonFluxPlot, 'Equiphoton Flux')
            xlabel(app.EquiphotonFluxPlot, '')
            ylabel(app.EquiphotonFluxPlot, 'Command Value')
            app.EquiphotonFluxPlot.XLim = [750 1080];
            app.PowerAtEquiphotonFlux = uiaxes(app.EquiphotonInspectorPanel);
            title(app.PowerAtEquiphotonFlux, 'Power at Equiphoton Flux')
            xlabel(app.PowerAtEquiphotonFlux, '')
            ylabel(app.PowerAtEquiphotonFlux, 'Power (mW)')
            app.PowerAtEquiphotonFlux.XLim = [750 1080];
            app.CommandValueSliderLabel = uilabel(app.EquiphotonInspectorPanel);
            app.CommandValueSliderLabel.Text = 'Command Value: ';
            app.CommandValueSlider = uislider(app.EquiphotonInspectorPanel);
            app.CommandValueSlider.Limits = [0 0.25];
            app.CommandValueSlider.ValueChangedFcn = createCallbackFcn(app, @CommandValueSliderValueChanged, true);
            app.CommandValueSlider.Value = 0.25;
            app.WavelengthSliderLabel = uilabel(app.EquiphotonInspectorPanel);
            app.WavelengthSliderLabel.Text = 'Wavelength: ';
            app.WavelengthSlider = uislider(app.EquiphotonInspectorPanel);
            app.WavelengthSlider.Limits = [750 1080];
            app.WavelengthSlider.MajorTicks = [750 800 850 900 950 1000 1050];
            app.WavelengthSlider.ValueChangedFcn = createCallbackFcn(app, @WavelengthSliderValueChanged, true);
            app.WavelengthSlider.Value = 800;
            app.CommandVoltageLabel = uilabel(app.EquiphotonInspectorPanel);
            app.CommandVoltageLabel.Text = '0.5 V';
            app.WavelengthLabel = uilabel(app.EquiphotonInspectorPanel);
            app.WavelengthLabel.Text = '800 nm';
            app.DataTable = uitable(app.EquiphotonInspectorPanel);
            app.DataTable.ColumnName = {'Wavelength'; 'Bias Voltage (V)'; 'Front Panel Bias'; 'Command Value (%)'; 'Power (mW)'};
            app.DataTable.RowName = {};
            app.PhotonsPerPulseData = uilabel(app.EquiphotonInspectorPanel);
            app.PhotonsPerPulseData.Text = '0';
            app.PhotonsPerPulseLabel = uilabel(app.EquiphotonInspectorPanel);
            app.PhotonsPerPulseLabel.Text = 'Photons Per Pulse: ';
            app.PowerAtThisCommandValueData = uilabel(app.EquiphotonInspectorPanel);
            app.PowerAtThisCommandValueData.Text = '0 mW';
            app.PoweratthisCommandValueLabel = uilabel(app.EquiphotonInspectorPanel);
            app.PoweratthisCommandValueLabel.Text = 'Power at this Command Value:';
            app.BiasVoltageatthisWavelengthData = uilabel(app.EquiphotonInspectorPanel);
            app.BiasVoltageatthisWavelengthData.Text = '0 V';
            app.BiasVoltageatthisWavelengthLabel = uilabel(app.EquiphotonInspectorPanel);
            app.BiasVoltageatthisWavelengthLabel.Text = 'Bias Voltage at this Wavelength:';
            app.FrontPanelBiasatthisWavelengthData = uilabel(app.EquiphotonInspectorPanel);
            app.FrontPanelBiasatthisWavelengthData.Text = '0';
            app.FrontPanelBiasatthisWavelengthLabel = uilabel(app.EquiphotonInspectorPanel);
            app.FrontPanelBiasatthisWavelengthLabel.Text = 'Front Panel Bias at this Wavelength:';
            app.FilenameEditFieldLabel = uilabel(app.EquiphotonInspectorPanel);
            app.FilenameEditFieldLabel.Text = 'Filename';
            app.FilenameEditField = uieditfield(app.EquiphotonInspectorPanel, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Clicky_GUI_For_Chrissi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end